classdef uireactivelistExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactiveListUIFigure     matlab.ui.Figure
        List1Panel               matlab.ui.container.Panel
        Load1                    matlab.ui.control.Button
        Load2                    matlab.ui.control.Button
        ListBox1Label            matlab.ui.control.Label
        ListBox1                 matlab.ui.control.ListBox
        DropDownLabel            matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        List2withItemsDataPanel  matlab.ui.container.Panel
        ListBox2Label            matlab.ui.control.Label
        ListBox2                 matlab.ui.control.ListBox
        SliderLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        SpinnerLabel             matlab.ui.control.Label
        Spinner                  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        ReactiveList1 % Description
        ReactiveList2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            items1 = "Item " + (1:5)';
            app.DropDown.Items = items1;
            % List 1
            app.ReactiveList1 = UI.ReactiveList(items1);
            app.ReactiveList1.bind(app.ListBox1);
            app.ReactiveList1.bindValue(app.EditField);
            app.ReactiveList1.bindValue(app.DropDown);
            % List 2
            items2 = "Item " + (1:10);
            items2Data = 1:10;
            app.ReactiveList2 = UI.ReactiveList(items2);
            app.ReactiveList2.bind(app.ListBox2);
            app.ReactiveList2.setData(items2Data);
            app.ReactiveList2.bindValue(app.Spinner);
            app.ReactiveList2.bindValue(app.Slider);
        end

        % Value changed function: ListBox1
        function ListBox1ValueChanged(app, event)
            app.ReactiveList1.select();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ReactiveList1.updateValue(event);
        end

        % Button pushed function: Load1
        function Load1Pushed(app, event)
            items = "Item " + (1:5)';
            app.DropDown.Items = items;
            app.ReactiveList1.update(items);
        end

        % Button pushed function: Load2
        function Load2Pushed(app, event)
            items = ["Red" "Green" "Blue"]';
            app.DropDown.Items = items;
            app.ReactiveList1.update(items);
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            app.ReactiveList2.select();
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.ReactiveList2.updateValue(event);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            app.ReactiveList2.updateValue(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactiveListUIFigure = uifigure('Visible', 'off');
            app.ReactiveListUIFigure.Name = 'Reactive List';
            app.List1Panel = uipanel(app.ReactiveListUIFigure);
            app.List1Panel.Title = 'List 1';
            app.Load1 = uibutton(app.List1Panel, 'push');
            app.Load1.ButtonPushedFcn = createCallbackFcn(app, @Load1Pushed, true);
            app.Load1.Text = 'Load 1';
            app.Load2 = uibutton(app.List1Panel, 'push');
            app.Load2.ButtonPushedFcn = createCallbackFcn(app, @Load2Pushed, true);
            app.Load2.Text = 'Load 2';
            app.ListBox1Label = uilabel(app.List1Panel);
            app.ListBox1Label.Text = 'List Box 1';
            app.ListBox1 = uilistbox(app.List1Panel);
            app.ListBox1.ValueChangedFcn = createCallbackFcn(app, @ListBox1ValueChanged, true);
            app.DropDownLabel = uilabel(app.List1Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.List1Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.EditFieldLabel = uilabel(app.List1Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.List1Panel, 'text');
            app.EditField.Editable = 'off';
            app.List2withItemsDataPanel = uipanel(app.ReactiveListUIFigure);
            app.List2withItemsDataPanel.Title = 'List 2 with Items Data';
            app.ListBox2Label = uilabel(app.List2withItemsDataPanel);
            app.ListBox2Label.Text = 'List Box 2';
            app.ListBox2 = uilistbox(app.List2withItemsDataPanel);
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.SliderLabel = uilabel(app.List2withItemsDataPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.List2withItemsDataPanel);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.SpinnerLabel = uilabel(app.List2withItemsDataPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.List2withItemsDataPanel);
            app.Spinner.Limits = [1 10];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            % Show the figure after all components are created
            app.ReactiveListUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uireactivelistExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactiveListUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactiveListUIFigure)
        end
    end
end